classdef imgediter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        panel1              matlab.ui.container.Panel
        output2Img          matlab.ui.control.Image
        GambarInputLabel_3  matlab.ui.control.Label
        output1Img          matlab.ui.control.Image
        GambarInputLabel_2  matlab.ui.control.Label
        GambarInputLabel    matlab.ui.control.Label
        inputImg            matlab.ui.control.Image
        prosesButton        matlab.ui.control.Button
        browseButton        matlab.ui.control.Button
        inputimgfield       matlab.ui.control.EditField
        initPanel           matlab.ui.container.Panel
        soalMenu            matlab.ui.container.ButtonGroup
        SoalLabel           matlab.ui.control.Label
        soal4button         matlab.ui.control.RadioButton
        soal3button         matlab.ui.control.RadioButton
        soal2button         matlab.ui.control.RadioButton
        soal1button         matlab.ui.control.RadioButton
        subSoal2Menu        matlab.ui.container.ButtonGroup
        SubSoal2Label       matlab.ui.control.Label
        soal2Dbutton        matlab.ui.control.RadioButton
        soal2Cbutton        matlab.ui.control.RadioButton
        soal2Bbutton        matlab.ui.control.RadioButton
        soal2Abutton        matlab.ui.control.RadioButton
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: soalMenu
        function soalMenuSelected(app, event)
            selectedButton = app.soalMenu.SelectedObject;
            if selectedButton == app.soal1button
                %app.subSoal1Menu.Visible = 'on';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'off';
                app.panel1.Visible ='on';
            end
            if selectedButton == app.soal2button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'on';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'off';
            end
            if selectedButton == app.soal3button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'on';
                %app.subSoal4Menu.Visible = 'off';
            end
            if selectedButton == app.soal4button
                %app.subSoal1Menu.Visible = 'off';
                app.panel1.Visible = 'off';
                app.subSoal2Menu.Visible = 'off';
                %app.subSoal3Menu.Visible = 'off';
                %app.subSoal4Menu.Visible = 'on';
            end
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
             %currentPath = pwd;
             %winopen(pwd);
             [file, path] = uigetfile({'*.jpg;*.png;*.jpeg','Image Files (*.jpg,*.png,*.jpeg)'}, ...
                              'Pilih Gambar');
            if isequal(file,0)
                disp('User batal memilih file');
            else
                % Gabungkan path + nama file
                %fullPath = fullfile(path, file);
                imgname = file;
                % Masukkan ke edit field
                app.inputimgfield.Value = imgname;
                app.inputImg.Visible = 'on';
                app.inputImg.ImageSource = fullfile(path, file);
                app.prosesButton.Visible='on';
            end
        end

        % Value changed function: inputimgfield
        function inputimgfieldValueChanged(app, event)
                value = app.inputimgfield.Value;  % ambil path dari edit field

                if isfile(value)
                    img = imread(value);
                    imshow(img,'Parent',app.UIAxes); % tampilkan di UIAxes
                else
                    uialert(app.UIFigure,'File tidak ditemukan','Error');
                end
            
        end

        % Button pushed function: prosesButton
        function prosesButtonPushed(app, event)
                % 1. Ambil gambar
            imageData = app.inputImg.ImageSource;
            img = imread(imageData);
        
            % 2. Lakukan 50 baris kode pemrosesan gambar yang rumit
            %    misalnya konversi grayscale, deteksi tepi, filtering, dll.
            grayImg = rgb2gray(img);
            edgeImg = edge(grayImg, 'sobel');
           
            finalImage = edgeImg;
        
            % 3. Tampilkan hasil
            app.output1Img.ImageSource = grayImg;
            app.output1Img.Visible = 'on';
            app.output2Img.ImageSource = finalImage;
            app.output2Img.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.initPanel = uipanel(app.UIFigure);
            app.initPanel.Title = 'Pengcit Editer';
            app.subSoal2Menu = uibuttongroup(app.initPanel);
            app.subSoal2Menu.Visible = 'off';
            app.soal2Abutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Abutton.Text = 'Soal a';
            app.soal2Abutton.Value = true;
            app.soal2Bbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Bbutton.Text = 'Soal b';
            app.soal2Cbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Cbutton.Text = 'Soal c';
            app.soal2Dbutton = uiradiobutton(app.subSoal2Menu);
            app.soal2Dbutton.Text = 'Soal d';
            app.SubSoal2Label = uilabel(app.subSoal2Menu);
            app.SubSoal2Label.Text = 'SubSoal 2';
            app.soalMenu = uibuttongroup(app.initPanel);
            app.soalMenu.SelectionChangedFcn = createCallbackFcn(app, @soalMenuSelected, true);
            app.soal1button = uiradiobutton(app.soalMenu);
            app.soal1button.Text = 'Soal 1';
            app.soal1button.Value = true;
            app.soal2button = uiradiobutton(app.soalMenu);
            app.soal2button.Text = 'Soal 2';
            app.soal3button = uiradiobutton(app.soalMenu);
            app.soal3button.Text = 'Soal 3';
            app.soal4button = uiradiobutton(app.soalMenu);
            app.soal4button.Text = 'Soal 4';
            app.SoalLabel = uilabel(app.soalMenu);
            app.SoalLabel.Text = 'Soal : ';
            app.panel1 = uipanel(app.UIFigure);
            app.inputimgfield = uieditfield(app.panel1, 'text');
            app.inputimgfield.ValueChangedFcn = createCallbackFcn(app, @inputimgfieldValueChanged, true);
            app.inputimgfield.Value = 'Gambar Input';
            app.browseButton = uibutton(app.panel1, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.prosesButton = uibutton(app.panel1, 'push');
            app.prosesButton.ButtonPushedFcn = createCallbackFcn(app, @prosesButtonPushed, true);
            app.prosesButton.Visible = 'off';
            app.prosesButton.Text = 'proses';
            app.inputImg = uiimage(app.panel1);
            app.inputImg.Visible = 'off';
            app.GambarInputLabel = uilabel(app.panel1);
            app.GambarInputLabel.Text = 'Gambar Input';
            app.GambarInputLabel_2 = uilabel(app.panel1);
            app.GambarInputLabel_2.Text = 'Gambar X';
            app.output1Img = uiimage(app.panel1);
            app.output1Img.Visible = 'off';
            app.GambarInputLabel_3 = uilabel(app.panel1);
            app.GambarInputLabel_3.Text = 'Gambar Y';
            app.output2Img = uiimage(app.panel1);
            app.output2Img.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgediter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end